classdef DataExport < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        FileMenu                      matlab.ui.container.Menu
        LoadmetadataMenu              matlab.ui.container.Menu
        SavemetadataMenu              matlab.ui.container.Menu
        SavemetadataasMenu            matlab.ui.container.Menu
        OptionsMenu                   matlab.ui.container.Menu
        ChangeDestinationRootDirMenu  matlab.ui.container.Menu
        ExitMenu                      matlab.ui.container.Menu
        HelpMenu                      matlab.ui.container.Menu
        AboutMenu                     matlab.ui.container.Menu
        SourcePanel                   matlab.ui.container.Panel
        SourcePathButton              matlab.ui.control.Button
        PathLabel                     matlab.ui.control.Label
        SourcePath                    matlab.ui.control.EditField
        SourceFileListBox             matlab.ui.control.ListBox
        NameLabel                     matlab.ui.control.Label
        SourceFileNameTemplate        matlab.ui.control.EditField
        LabelDropDown                 matlab.ui.control.Label
        SourceFileDateSortDropDown    matlab.ui.control.DropDown
        FormatLabel                   matlab.ui.control.Label
        SourceFileFormatDropDown      matlab.ui.control.DropDown
        ExportPanel                   matlab.ui.container.Panel
        ExportButton                  matlab.ui.control.Button
        IsParallelExport              matlab.ui.control.CheckBox
        ErrorFile                     matlab.ui.control.ListBox
        RefreshPeriodsecLabel         matlab.ui.control.Label
        RefreshPeriod                 matlab.ui.control.NumericEditField
        AutoExport                    matlab.ui.control.CheckBox
        CallbackDropDownLabel         matlab.ui.control.Label
        CallBack                      matlab.ui.control.DropDown
        ArgsLabel                     matlab.ui.control.Label
        CallBackArgs                  matlab.ui.control.EditField
        RefreshButton                 matlab.ui.control.Button
        SecondsUnitLabel              matlab.ui.control.Label
        SecondperUnit                 matlab.ui.control.NumericEditField
        DestinationPanel              matlab.ui.container.Panel
        DestinationPathButton         matlab.ui.control.Button
        PathLabel_3                   matlab.ui.control.Label
        DestinationPath               matlab.ui.control.EditField
        NameLabel_3                   matlab.ui.control.Label
        DestinationFileNameTemplate   matlab.ui.control.EditField
        LabelDropDown_3               matlab.ui.control.Label
        DestinationFileDateSortDropDown  matlab.ui.control.DropDown
        DestinationFileListBox        matlab.ui.control.ListBox
        UpdateMetadataButton          matlab.ui.control.Button
        StatusPanel                   matlab.ui.container.Panel
        LabelLamp                     matlab.ui.control.Label
        StatusLamp                    matlab.ui.control.Lamp
        MetadataPathLabel             matlab.ui.control.Label
        MetadataPath                  matlab.ui.control.EditField
        DestinationRootDirLabel       matlab.ui.control.Label
        DestinationRootDir            matlab.ui.control.EditField
    end

    
    properties (Access = public)
        SourceFileFolder % dir of files
        Timer % timer to do periodic refresh callback
        Metadata % metadata object to keep track of file info
        SourceFileDate % Description
        DestinationFileFolder % Description
        DestinationFileDate % Description
        Version = '1.10'
        Preferences = {'SourcePath', 'DestinationPath', 'SourceFileNameTemplate', ...
            'DestinationFileNameTemplate', 'SourceFileDateSortDropDown', ...
            'DestinationFileDateSortDropDown', 'SourceFileFormatDropDown', 'DestinationRootDir',...
            'IsParallelExport', 'RefreshPeriod', 'SecondperUnit', 'MetadataPath', ...
            'CallBack', 'CallBackArgs'};
    end
    
    methods (Access = public)
        
        function results = ExportData(app,sourcefilepath,destinationpath,sourcefileformat,...
                isparallelexport,callbackcell,varargin)
            if(exist(destinationpath,'dir')~=7)
                mkdir(destinationpath);
            end
            results=[];
            if ~isempty(sourcefilepath)
                app.ErrorFile.Items={};
                
                app.StatusLamp.Color = [0.12,0.85,0.12];
                app.LabelLamp.Text = 'Running';
                results = NeuroAnalysis.Base.EvalFun('NeuroAnalysis.IO.Export',...
                    [{sourcefilepath,destinationpath,sourcefileformat,isparallelexport,callbackcell},varargin]);
                
                status = cellfun(@(x)x.status,results);
                if any(~status)
                    hasSource = cellfun(@(x)isfield(x,'source'),results);
                    app.ErrorFile.Items = cellfun(@(x)x.source,results(~status&hasSource),'UniformOutput',false);
                    app.AutoExport.Value = false;
                    TimerCallbackChanged(app);
                end
                
                for r = 1:length(results)
                    if results{r}.status && ~isempty(results{r}.meta)
                        app.Metadata.addRow(results{r}.meta);
                    end
                end
                
                app.StatusLamp.Color = [0.12,0.15,0.12];
                app.LabelLamp.Text = 'Idle';
            end
        end
        
        function AutoExportData(app)
            RefreshButtonPushed(app);
            sourcefilename = app.SourceFileListBox.Items;
            if ~isempty(sourcefilename)
                [idx,loc] = ismember(NeuroAnalysis.Base.filenamenodirext(sourcefilename),...
                    NeuroAnalysis.Base.filenamenodirext(app.DestinationFileListBox.Items));
                iidx=idx;
                iidx(idx)=app.DestinationFileDate(nonzeros(loc)) > app.SourceFileDate(idx);
                idx = idx & iidx;
                sourcefilepath = cellfun(@(x,y)fullfile(x,y),app.SourceFileFolder(~idx)',...
                    sourcefilename(~idx),'UniformOutput',false);
                sourcefileformat = app.SourceFileFormatDropDown.Value;
                callbackcell = app.ParseCallback();
                args = {app,sourcefilepath,app.DestinationPath.Value,sourcefileformat,...
                    app.IsParallelExport.Value, callbackcell};
                switch sourcefileformat
                    case 'OI'
                        args = [args,{false}];
                    case 'Ripple'
                        args = [args,{app.SecondperUnit.Value}];
                end
                NeuroAnalysis.Base.EvalFun('ExportData',args);
            end
        end
        
        function SourceChanged(app)
            [dir,name,date] = NeuroAnalysis.Base.dirfile(app.SourcePath.Value,...
                app.SourceFileNameTemplate.Value,app.SourceFileDateSortDropDown.Value);
            app.SourceFileListBox.Items = name;
            app.SourceFileFolder = dir;
            app.SourceFileDate = date;
        end
        
        function DestinationChanged(app)
            [dir,name,date] = NeuroAnalysis.Base.dirfile(app.DestinationPath.Value,...
                app.DestinationFileNameTemplate.Value,app.DestinationFileDateSortDropDown.Value);
            app.DestinationFileListBox.Items = name;
            app.DestinationFileFolder = dir;
            app.DestinationFileDate = date;
        end
        
        function LoadMetadataFrom(app)
            [file, path] = uigetfile('*.mat', ...
                'Select metadata to load', app.MetadataPath.Value);
            if isa(file,'char')
                app.MetadataPath.Value = fullfile(path, file);
                LoadMetadata(app);
            else
                LoadMetadataFrom(app);
            end
        end
        
        function LoadMetadata(app)
            app.Metadata = NeuroAnalysis.IO.MetaTable(app.MetadataPath.Value);
        end
        
        function SaveMetadataAs(app)
            [file, path] = uiputfile('*.mat', ...
                'Choose a path to save metadata', app.MetadataPath.Value);
            if isa(file,'char')
                app.MetadataPath.Value = fullfile(path, file);
                SaveMetadata(app);
            else
                SaveMetadataAs(app);
            end
        end
        
        function SaveMetadata(app)
            if ~isempty(app.Metadata)
                % ensure metadata has been validated before save
                app.Metadata.validate(app.DestinationRootDir.Value,true);
                app.Metadata.export(app.MetadataPath.Value);
            end
        end
        
        function ChooseDestinationRootDir(app)
            rdir = uigetdir(app.DestinationRootDir.Value,'Choose Root Directory of DataExport Destination');
            if isa(rdir,'char')
                app.DestinationRootDir.Value = rdir;
            else
                ChooseDestinationRootDir(app);
            end
        end
        
        function TimerCallbackChanged(app)
            stop(app.Timer);
            if app.AutoExport.Value
                app.AutoExport.FontWeight = 'bold';
                app.AutoExport.FontColor = [0.82,0.32,0.32];
                app.Timer.TimerFcn = {@(~,~,app)AutoExportData(app),app};
            else
                app.AutoExport.FontWeight='normal';
                app.AutoExport.FontColor = [0,0,0];
                app.Timer.TimerFcn = {@(~,~,app)RefreshButtonPushed(app),app};
            end
            app.Timer.Period = app.RefreshPeriod.Value;
            start(app.Timer);
        end
        
        function Export(app)
            sourcefilename = app.SourceFileListBox.Value;
            if ~isempty(sourcefilename)
                [idx,~] = ismember(app.SourceFileListBox.Items,sourcefilename);
                sourcefilepath = cellfun(@(x,y)fullfile(x,y),app.SourceFileFolder(idx)',...
                    app.SourceFileListBox.Items(idx),'UniformOutput',false);
                sourcefileformat = app.SourceFileFormatDropDown.Value;
                callbackcell = app.ParseCallback();
                args = {app,sourcefilepath,app.DestinationPath.Value,sourcefileformat,...
                    app.IsParallelExport.Value,callbackcell};
                switch sourcefileformat
                    case 'OI'
                        args = [args,{true}];
                    case 'Ripple'
                        args = [args,{app.SecondperUnit.Value}];
                end
                NeuroAnalysis.Base.EvalFun('ExportData',args);
            end
        end
        
        function UpdateMetadata(app, varargin)
            app.StatusLamp.Color = [0.12,0.85,0.12];
            app.LabelLamp.Text = 'Running';
            
            files = cellfun(@fullfile,{app.DestinationFileFolder{:}}, ...
                app.DestinationFileListBox.Items, 'UniformOutput', false);
            files = setdiff(files, {app.MetadataPath.Value});
            args = {files, app.IsParallelExport.Value, varargin};
            results = NeuroAnalysis.Base.EvalFun('NeuroAnalysis.IO.CollectMetadata',args);
            status = cellfun(@(x)x.status,results);
            if any(~status)
                hasSource = cellfun(@(x)isfield(x,'source'),results);
                app.ErrorFile.Items = cellfun(@(x)x.source,results(~status&hasSource),'UniformOutput',false);
            end
            for r = 1:length(results)
                if results{r}.status && ~isempty(results{r}.meta)
                    app.Metadata.addRow(results{r}.meta);
                end
            end
            
            app.StatusLamp.Color = [0.12,0.15,0.12];
            app.LabelLamp.Text = 'Idle';
        end
        
        function callbackcell = ParseCallback(app)
            callback = app.CallBack.Value;
            args = regexp(app.CallBackArgs.Value, '\s?,\s?', 'split');
            empty = cellfun(@isempty,args);
            callbackcell = {callback, args(~empty)};
        end
        
        function LoadPrefs(app)
            for i = 1:length(app.Preferences)
                pname = app.Preferences{i};
                if ispref('NeuroAnalysis',pname) % pref exists
                    app.(pname).Value = getpref('NeuroAnalysis', pname);
                end
            end
        end
        
        function SavePrefs(app)
            for i = 1:length(app.Preferences)
                pname = app.Preferences{i};
                setpref('NeuroAnalysis', pname, app.(pname).Value);
            end
        end
        
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.LoadPrefs();
            
            t = timer;
            t.ExecutionMode='fixedSpacing';
            t.Period = app.RefreshPeriod.Value;
            t.TimerFcn = {@(~,~,app)RefreshButtonPushed(app),app};
            app.Timer = t;
            app.AutoExport.Value = false;
            
            if ~exist(app.MetadataPath.Value,'file')
                SaveMetadataAs(app);
            end
            LoadMetadata(app);
            if  ~exist(app.DestinationRootDir.Value,'dir')
                ChooseDestinationRootDir(app);
            end
            SourceChanged(app);
            DestinationChanged(app);
            start(app.Timer);
        end

        % Value changed function: AutoExport
        function AutoExportChanged(app, event)
            TimerCallbackChanged(app);
        end

        % Button pushed function: DestinationPathButton
        function ChooseDestinationPath(app, event)
            hr = uigetdir('','Choose Destination Path');
            if isa(hr,'char')
                app.DestinationPath.Value = hr;
                DestinationPathChanged(app);
            end
        end

        % Button pushed function: SourcePathButton
        function ChooseSourcePath(app, event)
            hr = uigetdir('','Choose Source Path');
            if isa(hr,'char')
                app.SourcePath.Value = hr;
                SourcePathChanged(app);
            end
        end

        % Value changed function: DestinationFileDateSortDropDown
        function DestinationFileDateSortChanged(app, event)
            DestinationChanged(app);
        end

        % Value changed function: DestinationFileNameTemplate
        function DestinationFileNameTemplateChanged(app, event)
            app.AutoExport.Value = false;
            TimerCallbackChanged(app);
            DestinationChanged(app);
        end

        % Value changed function: DestinationPath
        function DestinationPathChanged(app, event)
            app.AutoExport.Value = false;
            TimerCallbackChanged(app);
            DestinationChanged(app);
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            Export(app);
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            SourceChanged(app);
            DestinationChanged(app);
        end

        % Value changed function: RefreshPeriod
        function RefreshPeriodChanged(app, event)
            TimerCallbackChanged(app);
        end

        % Value changed function: SourceFileDateSortDropDown
        function SourceFileDateSortChanged(app, event)
            SourceChanged(app);
        end

        % Value changed function: SourceFileFormatDropDown
        function SourceFileFormatChanged(app, event)
            app.AutoExport.Value = false;
            TimerCallbackChanged(app);
        end

        % Value changed function: SourceFileNameTemplate
        function SourceFileNameTemplateChanged(app, event)
            app.AutoExport.Value = false;
            TimerCallbackChanged(app);
            SourceChanged(app);
        end

        % Value changed function: SourcePath
        function SourcePathChanged(app, event)
            app.AutoExport.Value = false;
            TimerCallbackChanged(app);
            SourceChanged(app);
        end

        % Callback function: ExitMenu, UIFigure
        function closerequestFcn(app, event)
            stop(app.Timer);
            delete(app.Timer);
            
            SaveMetadata(app);
            SavePrefs(app);
            
            delete(app);
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            disc = 'Export Signal and Experimental Dataset, Spike Sorting, Analysis and Register Metadata.';
            uiconfirm(app.UIFigure,{disc,'',['Version: ',app.Version]},'DataExport','Icon','info','Options',{'OK'});
        end

        % Button pushed function: UpdateMetadataButton
        function UpdateMetadataButtonPushed(app, event)
            UpdateMetadata(app);
        end

        % Menu selected function: LoadmetadataMenu
        function LoadmetadataMenuSelected(app, event)
            LoadMetadataFrom(app);
        end

        % Menu selected function: SavemetadataMenu
        function SavemetadataMenuSelected(app, event)
            SaveMetadata(app);
        end

        % Menu selected function: SavemetadataasMenu
        function SavemetadataasMenuSelected(app, event)
            SaveMetadataAs(app);
        end

        % Menu selected function: ChangeDestinationRootDirMenu
        function ChangeDestinationRootDirMenuSelected(app, event)
            ChooseDestinationRootDir(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'DataExport';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closerequestFcn, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.LoadmetadataMenu = uimenu(app.FileMenu);
            app.LoadmetadataMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadmetadataMenuSelected, true);
            app.LoadmetadataMenu.Text = 'Load metadata...';
            app.SavemetadataMenu = uimenu(app.FileMenu);
            app.SavemetadataMenu.MenuSelectedFcn = createCallbackFcn(app, @SavemetadataMenuSelected, true);
            app.SavemetadataMenu.Text = 'Save metadata';
            app.SavemetadataasMenu = uimenu(app.FileMenu);
            app.SavemetadataasMenu.MenuSelectedFcn = createCallbackFcn(app, @SavemetadataasMenuSelected, true);
            app.SavemetadataasMenu.Text = 'Save metadata as...';
            app.OptionsMenu = uimenu(app.FileMenu);
            app.OptionsMenu.Separator = 'on';
            app.OptionsMenu.Text = 'Options ...';
            app.ChangeDestinationRootDirMenu = uimenu(app.OptionsMenu);
            app.ChangeDestinationRootDirMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeDestinationRootDirMenuSelected, true);
            app.ChangeDestinationRootDirMenu.Text = 'Change Destination Root Dir';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @closerequestFcn, true);
            app.ExitMenu.Separator = 'on';
            app.ExitMenu.Text = 'Exit';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.SourcePanel = uipanel(app.UIFigure);
            app.SourcePanel.Title = 'Source';
            app.SourcePathButton = uibutton(app.SourcePanel, 'push');
            app.SourcePathButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseSourcePath, true);
            app.SourcePathButton.Text = '...';
            app.PathLabel = uilabel(app.SourcePanel);
            app.PathLabel.Text = 'Path';
            app.SourcePath = uieditfield(app.SourcePanel, 'text');
            app.SourcePath.ValueChangedFcn = createCallbackFcn(app, @SourcePathChanged, true);
            app.SourceFileListBox = uilistbox(app.SourcePanel);
            app.SourceFileListBox.Items = {};
            app.SourceFileListBox.Multiselect = 'on';
            app.SourceFileListBox.Value = {};
            app.NameLabel = uilabel(app.SourcePanel);
            app.NameLabel.Text = 'Name';
            app.SourceFileNameTemplate = uieditfield(app.SourcePanel, 'text');
            app.SourceFileNameTemplate.ValueChangedFcn = createCallbackFcn(app, @SourceFileNameTemplateChanged, true);
            app.LabelDropDown = uilabel(app.SourcePanel);
            app.LabelDropDown.Text = 'Modify Date';
            app.SourceFileDateSortDropDown = uidropdown(app.SourcePanel);
            app.SourceFileDateSortDropDown.Items = {'Descend', 'Ascend'};
            app.SourceFileDateSortDropDown.ValueChangedFcn = createCallbackFcn(app, @SourceFileDateSortChanged, true);
            app.SourceFileDateSortDropDown.Value = 'Descend';
            app.FormatLabel = uilabel(app.SourcePanel);
            app.FormatLabel.Text = 'Format';
            app.SourceFileFormatDropDown = uidropdown(app.SourcePanel);
            app.SourceFileFormatDropDown.Items = {'Ripple', 'OI', 'VLab', 'VisStim', 'Unknown'};
            app.SourceFileFormatDropDown.ValueChangedFcn = createCallbackFcn(app, @SourceFileFormatChanged, true);
            app.SourceFileFormatDropDown.Value = 'Ripple';
            app.ExportPanel = uipanel(app.UIFigure);
            app.ExportPanel.Title = 'Export';
            app.ExportButton = uibutton(app.ExportPanel, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.IsParallelExport = uicheckbox(app.ExportPanel);
            app.IsParallelExport.Text = 'Parallel Export';
            app.IsParallelExport.Value = true;
            app.ErrorFile = uilistbox(app.ExportPanel);
            app.ErrorFile.Items = {};
            app.ErrorFile.Multiselect = 'on';
            app.ErrorFile.Value = {};
            app.RefreshPeriodsecLabel = uilabel(app.ExportPanel);
            app.RefreshPeriodsecLabel.Text = 'RefreshPeriod(sec)';
            app.RefreshPeriod = uieditfield(app.ExportPanel, 'numeric');
            app.RefreshPeriod.Limits = [1 89400];
            app.RefreshPeriod.ValueChangedFcn = createCallbackFcn(app, @RefreshPeriodChanged, true);
            app.RefreshPeriod.Value = 10;
            app.AutoExport = uicheckbox(app.ExportPanel);
            app.AutoExport.ValueChangedFcn = createCallbackFcn(app, @AutoExportChanged, true);
            app.AutoExport.Text = 'AutoExport';
            app.CallbackDropDownLabel = uilabel(app.ExportPanel);
            app.CallbackDropDownLabel.Text = 'Callback';
            app.CallBack = uidropdown(app.ExportPanel);
            app.CallBack.Items = {'', 'dispatch'};
            app.CallBack.Editable = 'on';
            app.CallBack.Value = '';
            app.ArgsLabel = uilabel(app.ExportPanel);
            app.ArgsLabel.Text = 'Args';
            app.CallBackArgs = uieditfield(app.ExportPanel, 'text');
            app.RefreshButton = uibutton(app.ExportPanel, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Icon = 'noun_refresh_cc.png';
            app.RefreshButton.Text = '';
            app.SecondsUnitLabel = uilabel(app.ExportPanel);
            app.SecondsUnitLabel.Text = 'Seconds/Unit';
            app.SecondperUnit = uieditfield(app.ExportPanel, 'numeric');
            app.SecondperUnit.Limits = [1e-07 1000000];
            app.SecondperUnit.ValueDisplayFormat = '%11.6g';
            app.SecondperUnit.Value = 1;
            app.DestinationPanel = uipanel(app.UIFigure);
            app.DestinationPanel.Title = 'Destination';
            app.DestinationPathButton = uibutton(app.DestinationPanel, 'push');
            app.DestinationPathButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseDestinationPath, true);
            app.DestinationPathButton.Text = '...';
            app.PathLabel_3 = uilabel(app.DestinationPanel);
            app.PathLabel_3.Text = 'Path';
            app.DestinationPath = uieditfield(app.DestinationPanel, 'text');
            app.DestinationPath.ValueChangedFcn = createCallbackFcn(app, @DestinationPathChanged, true);
            app.NameLabel_3 = uilabel(app.DestinationPanel);
            app.NameLabel_3.Text = 'Name';
            app.DestinationFileNameTemplate = uieditfield(app.DestinationPanel, 'text');
            app.DestinationFileNameTemplate.ValueChangedFcn = createCallbackFcn(app, @DestinationFileNameTemplateChanged, true);
            app.LabelDropDown_3 = uilabel(app.DestinationPanel);
            app.LabelDropDown_3.Text = 'Modify Date';
            app.DestinationFileDateSortDropDown = uidropdown(app.DestinationPanel);
            app.DestinationFileDateSortDropDown.Items = {'Descend', 'Ascend'};
            app.DestinationFileDateSortDropDown.ValueChangedFcn = createCallbackFcn(app, @DestinationFileDateSortChanged, true);
            app.DestinationFileDateSortDropDown.Value = 'Descend';
            app.DestinationFileListBox = uilistbox(app.DestinationPanel);
            app.DestinationFileListBox.Items = {};
            app.DestinationFileListBox.Multiselect = 'on';
            app.DestinationFileListBox.Value = {};
            app.UpdateMetadataButton = uibutton(app.DestinationPanel, 'push');
            app.UpdateMetadataButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateMetadataButtonPushed, true);
            app.UpdateMetadataButton.Text = 'Update Metadata';
            app.StatusPanel = uipanel(app.UIFigure);
            app.LabelLamp = uilabel(app.StatusPanel);
            app.LabelLamp.Text = 'Idle';
            app.StatusLamp = uilamp(app.StatusPanel);
            app.MetadataPathLabel = uilabel(app.StatusPanel);
            app.MetadataPathLabel.Text = 'MetadataPath';
            app.MetadataPath = uieditfield(app.StatusPanel, 'text');
            app.MetadataPath.Editable = 'off';
            app.DestinationRootDirLabel = uilabel(app.StatusPanel);
            app.DestinationRootDirLabel.Text = 'DestinationRootDir';
            app.DestinationRootDir = uieditfield(app.StatusPanel, 'text');
            app.DestinationRootDir.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = DataExport

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end